classdef SASPDemoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image4                         matlab.ui.control.Image
        Hyperlink_3                    matlab.ui.control.Hyperlink
        Hyperlink_2                    matlab.ui.control.Hyperlink
        Hyperlink                      matlab.ui.control.Hyperlink
        YhdistoikeatilajaniraitaLabel  matlab.ui.control.Label
        TaputusjamuitatapojamitatahuoneenimpulssivasteLabel  matlab.ui.control.Label
        SASPDemoLabel                  matlab.ui.control.Label
        TarkistaButton                 matlab.ui.control.Button
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        PinkkiButton_3                 matlab.ui.control.RadioButton
        VihreButton_3                  matlab.ui.control.RadioButton
        SininenButton_3                matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        PinkkiButton_2                 matlab.ui.control.RadioButton
        VihreButton_2                  matlab.ui.control.RadioButton
        SininenButton_2                matlab.ui.control.RadioButton
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PinkkiButton                   matlab.ui.control.RadioButton
        VihreButton                    matlab.ui.control.RadioButton
        SininenButton                  matlab.ui.control.RadioButton
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
    end

    
    properties (Access = private)
        PlayButton          matlab.ui.control.Button
        Result1             double
        Fs1                 double
        Result2             double
        Fs2                 double
        Result3             double
        Fs3                 double
        TextArea            matlab.ui.control.TextArea
        CheckButton         matlab.ui.control.Button
        ImageAxes           matlab.ui.control.UIAxes
    end
    
    methods (Access = private)

         function checkButtonPushed(app, event)
            correctAnswerGroupKaytava = app.VihreButton; % Vihreä
            correctAnswerGroupU2 = app.PinkkiButton_2; % Pinkki
            correctAnswerGroupKokous = app.SininenButton_3; % Sininen  

            selectedValueGroup1 = app.ButtonGroup.SelectedObject;
            selectedValueGroup2 = app.ButtonGroup_2.SelectedObject;
            selectedValueGroup3 = app.ButtonGroup_3.SelectedObject;

            isCorrectAnswer = (selectedValueGroup1 == correctAnswerGroupKaytava) && (selectedValueGroup2== correctAnswerGroupU2) && (selectedValueGroup3 == correctAnswerGroupKokous);

            if isCorrectAnswer
                msgbox('WOOP WOOP!! Kaikki valinnat oikein :o', 'WOHOO!!!', 'modal');
            else
                msgbox('Nyyhkistä :( Yksi tai useampi valinta on väärin','Error','modal');
            end

         end
    

    end

%    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, addpath)
            % Load audio files and perform convolution

            % KOKOUS
            [y, app.Fs1] = audioread("KOKOUS.wav");
            [vega, ~] = audioread('vega.wav');
            app.Result1 = conv(vega, y);

            % U2
            [y, app.Fs2] = audioread("U2.wav");
            [vega, ~] = audioread('vega.wav');
            app.Result2 = conv(vega, y);

            % KÄYTÄVÄ
            [y, app.Fs3] = audioread("KÄYTÄVÄ.wav");
            [vega, ~] = audioread('vega.wav');
            app.Result3 = conv(vega, y);
          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

        soundsc(app.Result1,app.Fs1);
        
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
          
            soundsc(app.Result2,app.Fs2);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            soundsc(app.Result3,app.Fs3);
        end

        % Button pushed function: TarkistaButton
        function TarkistaButtonGroupButtonDown(app, event)
           
        checkButtonPushed(app,event)
    
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
     
           img = imread("KokousPlot.PNG");
           % Create a figure with no menus or toolbars
            fig = figure('Name', 'Sininen ilmapallo', 'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off');
            
            % Create axes and display the image
            ax = axes('Parent', fig);
            imshow(img, 'Parent', ax);
            
            % Add a close button callback
            set(fig, 'CloseRequestFcn', @closeFigure);

            function closeFigure(~, ~)
            % Callback function for the close button
                 closereq;
            end

            
        end

        % Callback function: Hyperlink_2
        function Hyperlink_2Clicked(app, event)
            img = imread("KäytäväPlot.PNG");
           % Create a figure with no menus or toolbars
            fig = figure('Name', 'Vihreä ilmapallo', 'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off');
            
            % Create axes and display the image
            ax = axes('Parent', fig);
            imshow(img, 'Parent', ax);
            
            % Add a close button callback
            set(fig, 'CloseRequestFcn', @closeFigure);

            function closeFigure(~, ~)
            % Callback function for the close button
                 closereq;
            end
        end

        % Callback function: Hyperlink_3
        function Hyperlink_3Clicked(app, event)
            img = imread("U2Plot.PNG");
           % Create a figure with no menus or toolbars
            fig = figure('Name', 'Pinkki ilmapallo', 'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off');
            
            % Create axes and display the image
            ax = axes('Parent', fig);
            imshow(img, 'Parent', ax);
            
            % Add a close button callback
            set(fig, 'CloseRequestFcn', @closeFigure);

            function closeFigure(~, ~)
            % Callback function for the close button
                 closereq;
            end
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            img = imread("info.PNG");
           % Create a figure with no menus or toolbars
            fig = figure('Name', 'Vihreä ilmapallo', 'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off');
            
            % Create axes and display the image
            ax = axes('Parent', fig);
            imshow(img, 'Parent', ax);
            
            % Add a close button callback
            set(fig, 'CloseRequestFcn', @closeFigure);

            function closeFigure(~, ~)
            % Callback function for the close button
                 closereq;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'balloon-png-28091.png';
            app.Button.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'balloon-png-28091 pinkki.png';
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'balloon-png-28091vihree.png';
            app.Button_2.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Kaytava.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Sali.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Kokous.png';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SininenButton = uiradiobutton(app.ButtonGroup);
            app.SininenButton.Text = 'Sininen';
            app.SininenButton.Value = true;
            app.VihreButton = uiradiobutton(app.ButtonGroup);
            app.VihreButton.Text = 'Vihreä';
            app.PinkkiButton = uiradiobutton(app.ButtonGroup);
            app.PinkkiButton.Text = 'Pinkki';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SininenButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.SininenButton_2.Text = 'Sininen';
            app.SininenButton_2.Value = true;
            app.VihreButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.VihreButton_2.Text = 'Vihreä';
            app.PinkkiButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.PinkkiButton_2.Text = 'Pinkki';
            app.ButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.SininenButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.SininenButton_3.Text = 'Sininen';
            app.SininenButton_3.Value = true;
            app.VihreButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.VihreButton_3.Text = 'Vihreä';
            app.PinkkiButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.PinkkiButton_3.Text = 'Pinkki';
            app.TarkistaButton = uibutton(app.UIFigure, 'push');
            app.TarkistaButton.ButtonPushedFcn = createCallbackFcn(app, @TarkistaButtonGroupButtonDown, true);
            app.TarkistaButton.Text = 'Tarkista';
            app.SASPDemoLabel = uilabel(app.UIFigure);
            app.SASPDemoLabel.Text = {'SASP Demo'; ''};
            app.TaputusjamuitatapojamitatahuoneenimpulssivasteLabel = uilabel(app.UIFigure);
            app.TaputusjamuitatapojamitatahuoneenimpulssivasteLabel.Text = 'Taputus ja muita tapoja mitata huoneen impulssivaste';
            app.YhdistoikeatilajaniraitaLabel = uilabel(app.UIFigure);
            app.YhdistoikeatilajaniraitaLabel.Text = 'Yhdistä oikea tila ja ääniraita:';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Näytä kuvaaja';
            app.Hyperlink_2 = uihyperlink(app.UIFigure);
            app.Hyperlink_2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_2Clicked, true);
            app.Hyperlink_2.Text = 'Näytä kuvaaja';
            app.Hyperlink_3 = uihyperlink(app.UIFigure);
            app.Hyperlink_3.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_3Clicked, true);
            app.Hyperlink_3.Text = 'Näytä kuvaaja';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'help.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SASPDemoApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end